classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            tmp={'正弦信号幅值','正弦信号相角'};
            defans={'1','0'};
            app.Property=inputdlg(tmp,'输入参数',1,defans,'on');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            helpdlg('输入幅值的范围应>0','Help')
            tmp = questdlg('是否确认？','确认？','Yes','No','Yes');
            switch tmp
                case 'Yes'
                    helpdlg({'幅值为：',app.Property{1,1},'相角为：',app.Property{2,1}}, 'show')
                    x=0:0.1*pi:2*pi;
                    xx(:)=x(:)+str2double(app.Property{2,1});
                    y= str2double(app.Property{1,1})*sin(xx);
                    plot(app.UIAxes,x,y)
                case 'No'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '输入';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '绘制';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end