classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Output_field  matlab.ui.control.NumericEditField
        Input_field   matlab.ui.control.EditField
        Button1       matlab.ui.control.Button
        Button2       matlab.ui.control.Button
        Button3       matlab.ui.control.Button
        Button4       matlab.ui.control.Button
        Button5       matlab.ui.control.Button
        Button6       matlab.ui.control.Button
        Button7       matlab.ui.control.Button
        Button8       matlab.ui.control.Button
        Button9       matlab.ui.control.Button
        Button0       matlab.ui.control.Button
        Button_ADD    matlab.ui.control.Button
        Button_SUB    matlab.ui.control.Button
        Button_MUL    matlab.ui.control.Button
        Button_DIV    matlab.ui.control.Button
        Button_CE     matlab.ui.control.Button
        Button_LB     matlab.ui.control.Button
        Button_RB     matlab.ui.control.Button
        Button_EQUAL  matlab.ui.control.Button
        Label         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'1');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'2');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'3');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'4');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'5');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'6');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'7');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'8');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'9');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button0
        function Button0Pushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'0');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_CE
        function Button_CEPushed(app, event)
            app.Input_field.Value = '';
            app.Output_field.Value = 0;
        end

        % Button pushed function: Button_ADD
        function Button_ADDPushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'+');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_SUB
        function Button_SUBPushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'-');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_MUL
        function Button_MULPushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'*');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_DIV
        function Button_DIVPushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'/');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_LB
        function Button_LBPushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,'(');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_RB
        function Button_RBPushed(app, event)
            s1=app.Input_field.Value;
            s2=strcat(s1,')');
            app.Input_field.Value=s2;
        end

        % Button pushed function: Button_EQUAL
        function Button_EQUALPushed(app, event)
            s1=app.Input_field.Value;
            app.Output_field.Value = eval(s1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Output_field = uieditfield(app.UIFigure, 'numeric');
            app.Input_field = uieditfield(app.UIFigure, 'text');
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '3';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '4';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '5';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '6';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '7';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '8';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '9';
            app.Button0 = uibutton(app.UIFigure, 'push');
            app.Button0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button0.Text = '0';
            app.Button_ADD = uibutton(app.UIFigure, 'push');
            app.Button_ADD.ButtonPushedFcn = createCallbackFcn(app, @Button_ADDPushed, true);
            app.Button_ADD.Text = '+';
            app.Button_SUB = uibutton(app.UIFigure, 'push');
            app.Button_SUB.ButtonPushedFcn = createCallbackFcn(app, @Button_SUBPushed, true);
            app.Button_SUB.Text = '-';
            app.Button_MUL = uibutton(app.UIFigure, 'push');
            app.Button_MUL.ButtonPushedFcn = createCallbackFcn(app, @Button_MULPushed, true);
            app.Button_MUL.Text = '×';
            app.Button_DIV = uibutton(app.UIFigure, 'push');
            app.Button_DIV.ButtonPushedFcn = createCallbackFcn(app, @Button_DIVPushed, true);
            app.Button_DIV.Text = '÷';
            app.Button_CE = uibutton(app.UIFigure, 'push');
            app.Button_CE.ButtonPushedFcn = createCallbackFcn(app, @Button_CEPushed, true);
            app.Button_CE.Text = 'AC';
            app.Button_LB = uibutton(app.UIFigure, 'push');
            app.Button_LB.ButtonPushedFcn = createCallbackFcn(app, @Button_LBPushed, true);
            app.Button_LB.Text = '(';
            app.Button_RB = uibutton(app.UIFigure, 'push');
            app.Button_RB.ButtonPushedFcn = createCallbackFcn(app, @Button_RBPushed, true);
            app.Button_RB.Text = ')';
            app.Button_EQUAL = uibutton(app.UIFigure, 'push');
            app.Button_EQUAL.ButtonPushedFcn = createCallbackFcn(app, @Button_EQUALPushed, true);
            app.Button_EQUAL.Text = '=';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end